classdef DTItool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DTItoolbox                      matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportModelMenu                 matlab.ui.container.Menu
        ExportModelMenu                 matlab.ui.container.Menu
        ImportBoundsMenu                matlab.ui.container.Menu
        ExportBoundsMenu                matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ExcludeExchangeReactionsMenu    matlab.ui.container.Menu
        ExcludeDiffusionReactionsMenu   matlab.ui.container.Menu
        ExcludeSpontaneousReactionsMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        UITable                         matlab.ui.control.Table
        ShowOnlyExchangeReactionsCheckBox  matlab.ui.control.CheckBox
        ObjectivetoAttackButtonGroup    matlab.ui.container.ButtonGroup
        ProliferationBiomassProductionButton  matlab.ui.control.RadioButton
        VirulenceFactorProductionButton  matlab.ui.control.RadioButton
        ReportBasedonButtonGroup        matlab.ui.container.ButtonGroup
        ReactionSetsButton              matlab.ui.control.RadioButton
        GeneSetsButton                  matlab.ui.control.RadioButton
        UITable2                        matlab.ui.control.Table
        MetaboliteCentricApproachCheckBox  matlab.ui.control.CheckBox
        StartAnalysisButton             matlab.ui.control.Button
        AddtoTargetsButton              matlab.ui.control.Button
        MaximumCardinalitySpinnerLabel  matlab.ui.control.Label
        MaximumCardinalitySpinner       matlab.ui.control.Spinner
        CutoffRatioSpinnerLabel         matlab.ui.control.Label
        CutoffRatioSpinner              matlab.ui.control.Spinner
        ViewListofTargetsButton         matlab.ui.control.Button
        RemoveAddedTargetsButton        matlab.ui.control.Button
        NumberofWorkersLabel            matlab.ui.control.Label
        NumberofWorkersSpinner          matlab.ui.control.Spinner
        ResetNoofWorkersButton          matlab.ui.control.Button
        SearchEditFieldLabel            matlab.ui.control.Label
        SearchEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Model = [];
        Data = [];
        menu = [];
        NWorkers = 4;
        VFindex = [];
        DemandRxnName = [];
        AddedIndex = [];
        MetCentRxnIds = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            var = load('Eco_iAF1260');
            fieldName = fieldnames(var);
            model = getfield(var, fieldName{1});
            app.Model = model;
            app.UITable.Data = [app.Model.rxns, num2cell(app.Model.lb), num2cell(app.Model.ub)];
            app.Data = app.UITable.Data;
            %             app.UITable.RowName = num2cell((1 : length(app.Model.rxns))');
            app.UITable2.Data = app.Model.mets;
            app.UITable2.Enable = 'off';
            %             for i = 1 : length(app.Model.mets)
            %                 RowNames{i, 1} = i;
            %             end
            %             app.UITable2.RowName = RowNames;
            app.AddtoTargetsButton.Enable = 'off';
            app.ViewListofTargetsButton.Enable = 'off';
            app.RemoveAddedTargetsButton.Enable = 'off';
            app.SearchEditField.Enable = 'off';
            app.SearchEditFieldLabel.Enable = 'off';
            app.ResetNoofWorkersButton.Enable = 'off';
            
            d = uiprogressdlg(app.DTItoolbox,'Title','Please Wait',...
                'Message','Starting the Parallel Pool.');
            pause(0.5)
            d.Value = 0.33;
            pause(0.1)
            try
                parpool('local',app.NWorkers);
                disp('The parpool is ready')
            catch
                disp('The parpool is ready')
            end
            d.Value = 1;
            pause(0.1)
            % Close dialog box
            close(d);
        end

        % Callback function
        function LoadModelButtonPushed(app, event)
            
        end

        % Value changed function: ShowOnlyExchangeReactionsCheckBox
        function ShowOnlyExchangeReactionsCheckBoxValueChanged(app, event)
            value = app.ShowOnlyExchangeReactionsCheckBox.Value;
            if value == 0
                app.UITable.Data = app.Data;
            else
                ExchangeRxns = findExcRxns(app.Model);
                app.UITable.Data = app.Data(ExchangeRxns, :);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            value = app.ShowOnlyExchangeReactionsCheckBox.Value;
            app.UITable.Data(indices(1), indices(2)) = num2cell(newData);
            
            if value == 0
                app.UITable.Data(indices(1), indices(2)) = num2cell(newData);
                app.Data(indices(1), indices(2)) = num2cell(newData);
                info = app.Data;
                lb = cell2mat(info(:,2));
                ub = cell2mat(info(:,3));
                app.Model.lb = lb;
                app.Model.ub = ub;
            else
                ExchangeRxns = find(findExcRxns(app.Model));
                ind = ExchangeRxns(indices(1));
                app.UITable.Data(indices(1), indices(2)) = num2cell(newData);
                app.Data(ind, indices(2)) = num2cell(newData);
                info = app.Data;
                lb = cell2mat(info(:,2));
                ub = cell2mat(info(:,3));
                app.Model.lb = lb;
                app.Model.ub = ub;
            end
        end

        % Menu selected function: ImportModelMenu
        function ImportModelMenuSelected(app, event)
            [File, Path] = uigetfile('.mat', 'Please select the model', '*.mat');
            filename = fullfile(Path, File);
            var = load(filename);
            fieldName = fieldnames(var);
            model = getfield(var, fieldName{1});
            app.Model = model;
            app.UITable.Data = [app.Model.rxns, num2cell(app.Model.lb), num2cell(app.Model.ub)];
            app.Data = app.UITable.Data;
            app.UITable2.Data = app.Model.metNames;
            app.VFindex = [];
            app.DemandRxnName = [];
        end

        % Selection changed function: ObjectivetoAttackButtonGroup
        function ObjectivetoAttackButtonGroupSelectionChanged(app, event)
            selectedButton = app.ObjectivetoAttackButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Virulence Factor Production'
                    app.UITable2.Enable = 'on';
                    app.AddtoTargetsButton.Enable = 'on';
                    app.ViewListofTargetsButton.Enable = 'on';
                    app.RemoveAddedTargetsButton.Enable = 'on';
                    app.SearchEditField.Enable = 'on';
                    app.SearchEditFieldLabel.Enable = 'on';
                otherwise
                    app.UITable2.Enable = 'off';
                    app.AddtoTargetsButton.Enable = 'off';
                    app.ViewListofTargetsButton.Enable = 'off';
                    app.RemoveAddedTargetsButton.Enable = 'off';
                    app.SearchEditField.Enable = 'off';
                    app.SearchEditFieldLabel.Enable = 'off';
            end
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            model = app.Model;
            switch app.ExcludeExchangeReactionsMenu.Checked
                case 'on'
                    eliList1 = strfind(lower(model.rxnNames), lower('exchange'));
                    diffustion_reactions = {'ACALDtpp', 'CO2tpp', 'FALDtpp', 'H2Otpp', 'H2tpp', 'N2Otpp', 'NOtpp', 'O2tpp',  'SO2tpp'};
                    Additional = { 'H2Otex', 'ATPM', 'CPGNexs','FE3HOXexs'};
                otherwise
                    eliList1 = strfind(lower(model.rxnNames), lower('-----------'));
                    Additional = {};
                    diffustion_reactions = {};
            end
            
            switch app.ExcludeDiffusionReactionsMenu.Checked
                case 'on'
                    eliList2 = strfind(lower(model.rxnNames), lower('Diffusion13'));
                otherwise
                    eliList2 = strfind(lower(model.rxnNames), lower('-----------'));
            end
            
            switch app.ExcludeSpontaneousReactionsMenu.Checked
                case 'on'
                    eliList3 = strfind(lower(model.rxnNames), lower('sink needed'));
                    eliList4 = strfind(lower(model.rxnNames), lower('spontaneous'));
                otherwise
                    eliList3 = strfind(lower(model.rxnNames), lower('-----------'));
                    eliList4 = strfind(lower(model.rxnNames), lower('-----------'));
            end
            eliList = false(1, length(model.rxnNames));
            for i = 1 : length(model.rxnNames)
                eliList(i) = ~isempty(eliList1{i,1}) | ~isempty(eliList2{i,1}) | ~isempty(eliList3{i,1}) | ~isempty(eliList4{i,1});
            end
            eliList = [find(eliList) , findRxnIDs(model, Additional), findRxnIDs(model, diffustion_reactions)];
            if ~isempty(app.MetCentRxnIds)
                eliList = unique([setdiff(1:length(app.Model.rxns), app.MetCentRxnIds'), eliList]);
            end
            if ~isempty(app.DemandRxnName)
                RxnsAdded = findRxnIDs(app.Model, app.DemandRxnName);
                eliList = [eliList, RxnsAdded'];
            end
            
            selectedButtonAttack = app.ObjectivetoAttackButtonGroup.SelectedObject;
            selectedButtonResult = app.ReportBasedonButtonGroup.SelectedObject;
            MaximumCardinality = app.MaximumCardinalitySpinner.Value;
            switch selectedButtonResult.Text
                case 'Reaction Sets'
                    Mode = 'Rxn';
                case 'Gene Sets'
                    Mode = 'Gene';
            end
            switch selectedButtonAttack.Text
                case 'Proliferation (Biomass Production)'
                    LethalSets = RapidSL(app.Model, MaximumCardinality, [], app.Model.rxns(eliList), Mode);
                    
                    n = 1;
                    for ii = 1 : MaximumCardinality
                        for jj = 1 : length(LethalSets{ii})
                            Sets{n,1} = strjoin(LethalSets{ii}(jj, :), ', ');
                            n = n + 1;
                        end
                    end
                    filter = {'*.xls'};
                    [file, path] = uiputfile(filter);
                    writecell(Sets, [path, file]);
                case 'Virulence Factor Production'
                    for i = 1 : length(app.DemandRxnName)
                        model = app.Model;
                        model = changeObjective(model, app.DemandRxnName{i}, 1);
                        Targets = RapidSL(model, MaximumCardinality, [], app.Model.rxns(eliList), Mode);
                        for j = 1 : MaximumCardinality
                            if strcmp(Mode, 'Rxn')
                                Targets{j} = findRxnIDs(app.Model, Targets{j});
                            else
                                Targets{j} = findGeneIDs(app.Model, Targets{j});
                            end
                        end
                        n = 1;
                        Sets = {};
                        for ii = 1 : MaximumCardinality
                            for jj = 1 : length(Targets{ii})
                                Sets{n,1} = Targets{ii}(jj, :);
                                n = n + 1;
                            end
                        end
                        TargetSets{i} = Sets;
                        NumberofCases(i) = length(Sets);
                    end
                    if any(NumberofCases == 0)
                        NoResultRxns = app.DemandRxnName{NumberofCases == 0}(3:end);
                        message = sprintf(['No Results! \n No solution is found for these targets: \n' ...
                            strjoin(NoResultRxns, ', ')]);
                        uialert(app.DTItoolbox,message,'No Resluts Found',...
                            'Icon','error');
                    else
                        Text = '';
                        for ii = 1 : length(app.DemandRxnName)
                            Text = ['nchoosek(1:NumberofCases(',num2str(length(app.DemandRxnName)-ii+1),'), 1)'',', Text];
                        end
                        Text = Text(1:end-1);
                        Text = ['c = combvec(', Text,')'';'];
                        eval(Text);
                        
                        Sets = cell(length(app.DemandRxnName)*MaximumCardinality, 1);
                        for ii = 1 : length(c)
                            Set = [];
                            for jj = 1 : length(c(1,:))
                                Set = unique([cell2mat(TargetSets{jj}(c(ii, jj))), Set]);
                            end
                            m = length(Set);
                            Sets{m} = [Sets{m}; Set];
                        end
                        for ii = 1 : length(Sets)
                            Sets{ii} = unique(Sets{ii}, 'rows');
                        end
                        for ii = 1 : length(Sets)-1
                            Set = Sets{ii};
                            for jj = ii+1 : length(Sets)
                                Indx = false(length(Sets{jj}(:, 1)), 1);
                                for kk = 1 : length(Set(:, 1))
                                    eql = Sets{jj} ==  Set(kk, 1);
                                    for mm = 2 : length(Set(kk, :))
                                        eql = Sets{jj} ==  Set(kk, mm) | eql;
                                    end
                                    Indx = sum(eql, 2) >= length(Set(kk, :)) | Indx;
                                end
                                Sets{jj}(Indx, :) = [];
                            end
                        end
                        for i = 1 : MaximumCardinality * length(app.DemandRxnName)
                            if strcmp(Mode, 'Rxn')
                                Sets{i} = app.Model.rxns(Sets{i});
                            else
                                Sets{i} = app.Model.genes(Sets{i});
                            end
                        end
                        LethalSets = Sets;
                        Sets = {};
                        n = 1;
                        for ii = 1 : MaximumCardinality * length(app.DemandRxnName)
                            for jj = 1 : length(LethalSets{ii})
                                Sets{n,1} = strjoin(LethalSets{ii}(jj, :), ', ');
                                n = n + 1;
                            end
                        end
                        filter = {'*.xls'};
                        [file, path] = uiputfile(filter);
                        writecell(Sets, [path, file]);
                    end
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            
        end

        % Callback function
        function NumberofWorkersMenuSelected(app, event)
            
        end

        % Menu selected function: ExportBoundsMenu
        function ExportBoundsMenuSelected(app, event)
            filter = {'*.xls'};
            [file, path] = uiputfile(filter);
            writecell(app.Data,[path, file]);
            message = sprintf('Done! \n Excel file is generated.');
            uialert(app.DTItoolbox,message,'Sucssess',...
                'Icon','success');
        end

        % Button pushed function: AddtoTargetsButton
        function AddtoTargetsButtonPushed(app, event)
            model = app.Model;
            Index = app.VFindex;
            Index = find(strcmp(app.Model.mets, app.UITable2.Data(Index)));
            if ~ismember(Index, app.AddedIndex)
                app.AddedIndex = [app.AddedIndex; Index];
                if isempty(Index)
                    message = sprintf('Please Select a Metabolite From the Table');
                    uialert(app.DTItoolbox,message,'No Metabolite Is Selected',...
                        'Icon','warning');
                else
                    [model, RxnName] = addDemandReaction(model, model.mets(Index));
                    app.Model = model;
                    app.DemandRxnName = [app.DemandRxnName; RxnName];
                    A = app.DemandRxnName;
                end
            else
                message = sprintf('This Metabolite Is Already Added!');
                uialert(app.DTItoolbox,message,'Duplicate Targets!',...
                    'Icon','warning');
            end
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            app.VFindex = indices(1);
        end

        % Button pushed function: ViewListofTargetsButton
        function ViewListofTargetsButtonPushed(app, event)
            if ~isempty(app.DemandRxnName)
                Text = [];
                for i = 1 : length(app.DemandRxnName(:, 1))
                    Text = [app.DemandRxnName{i, :}(4:end), ' \n ', Text];
                end
                message = sprintf(['The List of Virulence Factors Are: \n', ...
                    Text]);
                uialert(app.DTItoolbox,message,'List of Targets',...
                    'Icon','success');
            else
                message = sprintf(['No Virulence Factor Has Been Selected!']);
                uialert(app.DTItoolbox,message,'No Target!',...
                    'Icon','warning');
            end
        end

        % Button pushed function: ResetNoofWorkersButton
        function ResetNoofWorkersButtonPushed(app, event)
            poolobj = gcp('nocreate');
            delete(poolobj);
            app.NWorkers = app.NumberofWorkersSpinner.Value;
            d = uiprogressdlg(app.DTItoolbox,'Title','Please Wait',...
                'Message','Starting the Parallel Pool.');
            pause(0.5)
            d.Value = 0.33;
            pause(0.1)
            parpool('local',app.NWorkers);
            d.Value = 1;
            pause(0.1)
            % Close dialog box
            close(d);
            disp('The parpool is ready')
            app.ResetNoofWorkersButton.Enable = 'off';
        end

        % Button pushed function: RemoveAddedTargetsButton
        function RemoveAddedTargetsButtonPushed(app, event)
            model = app.Model;
            model = removeRxns(model, app.DemandRxnName);
            app.DemandRxnName = [];
            app.VFindex = [];
            app.Model = model;
        end

        % Menu selected function: ImportBoundsMenu
        function ImportBoundsMenuSelected(app, event)
            try
                model = app.Model;
                [file,path] = uigetfile('*.xls');
                fileName = [path, file];
                lb = readmatrix(fileName, 'Range', 'B:B');
                ub = readmatrix(fileName, 'Range', 'C:C');
                model.lb = lb;
                model.ub = ub;
                app.UITable.Data = [model.rxns, num2cell(model.lb), num2cell(model.ub)];
                app.Data = app.UITable.Data;
                message = sprintf('Reaction Bounds Were Updated!');
                uialert(app.DTItoolbox,message,'Update',...
                    'Icon','success');
                app.Model = model;
            catch
                app.UITable.Data = [app.Model.rxns, num2cell(app.Model.lb), num2cell(app.Model.ub)];
                app.Data = app.UITable.Data;
                message = sprintf('There Were Some Issues in the Input File!');
                uialert(app.DTItoolbox,message,'Update',...
                    'Icon','error');
            end
        end

        % Menu selected function: ExcludeExchangeReactionsMenu
        function ExcludeExchangeReactionsMenuSelected(app, event)
            switch app.ExcludeExchangeReactionsMenu.Checked
                case 'on'
                    app.ExcludeExchangeReactionsMenu.Checked = 'off';
                case 'off'
                    app.ExcludeExchangeReactionsMenu.Checked = 'on';
            end
            
        end

        % Value changed function: MetaboliteCentricApproachCheckBox
        function MetaboliteCentricApproachCheckBoxValueChanged(app, event)
            value = app.MetaboliteCentricApproachCheckBox.Value;
            if value == 1
                model = app.Model;
                S = full(model.S);
                Sp = S;
                Sp(Sp < 0) = 0;
                Sn = S;
                Sn(Sn > 0) = 0;
                ChokePointp = sum(logical(Sp), 2) == 1;
                ChokePointn = sum(logical(Sn), 2) == 1;
                RxnIndxp = find(sum(logical(S(ChokePointp, :))) > 0);
                RxnIndxn = find(sum(logical(S(ChokePointn, :))) > 0);
                RxnIndx = unique([RxnIndxn, RxnIndxp]');
                app.MetCentRxnIds = RxnIndx;
            else
                app.MetCentRxnIds = [];
            end
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            changingValue = event.Value;
            Mets = app.Model.mets;
            Matched = contains(Mets, changingValue);
            app.UITable2.Data = app.Model.mets(Matched);
        end

        % Value changed function: NumberofWorkersSpinner
        function NumberofWorkersSpinnerValueChanged(app, event)
            value = app.NumberofWorkersSpinner.Value;
            if value ~= app.NWorkers
                app.ResetNoofWorkersButton.Enable = 'on';
            else
                app.ResetNoofWorkersButton.Enable = 'off';
            end
        end

        % Menu selected function: ExportModelMenu
        function ExportModelMenuSelected(app, event)
            [fileName, Path] = uiputfile('*.mat', 'Saving Model', 'Model');
            model = app.Model;
            save([Path, fileName], 'model');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DTItoolbox = uifigure('Visible', 'off');
            app.DTItoolbox.Name = 'UI Figure';
            app.FileMenu = uimenu(app.DTItoolbox);
            app.FileMenu.Text = 'File';
            app.ImportModelMenu = uimenu(app.FileMenu);
            app.ImportModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportModelMenuSelected, true);
            app.ImportModelMenu.Text = 'Import Model';
            app.ExportModelMenu = uimenu(app.FileMenu);
            app.ExportModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportModelMenuSelected, true);
            app.ExportModelMenu.Text = 'Export Model';
            app.ImportBoundsMenu = uimenu(app.FileMenu);
            app.ImportBoundsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportBoundsMenuSelected, true);
            app.ImportBoundsMenu.Text = 'Import Bounds';
            app.ExportBoundsMenu = uimenu(app.FileMenu);
            app.ExportBoundsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportBoundsMenuSelected, true);
            app.ExportBoundsMenu.Text = 'Export Bounds';
            app.OptionsMenu = uimenu(app.DTItoolbox);
            app.OptionsMenu.Text = 'Options';
            app.ExcludeExchangeReactionsMenu = uimenu(app.OptionsMenu);
            app.ExcludeExchangeReactionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludeExchangeReactionsMenuSelected, true);
            app.ExcludeExchangeReactionsMenu.Checked = 'on';
            app.ExcludeExchangeReactionsMenu.Text = 'Exclude Exchange Reactions';
            app.ExcludeDiffusionReactionsMenu = uimenu(app.OptionsMenu);
            app.ExcludeDiffusionReactionsMenu.Checked = 'on';
            app.ExcludeDiffusionReactionsMenu.Text = 'Exclude Diffusion Reactions';
            app.ExcludeSpontaneousReactionsMenu = uimenu(app.OptionsMenu);
            app.ExcludeSpontaneousReactionsMenu.Checked = 'on';
            app.ExcludeSpontaneousReactionsMenu.Text = 'Exclude Spontaneous Reactions';
            app.HelpMenu = uimenu(app.DTItoolbox);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.UITable = uitable(app.DTItoolbox);
            app.UITable.ColumnName = {'Reaction'; 'lb'; 'ub'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ShowOnlyExchangeReactionsCheckBox = uicheckbox(app.DTItoolbox);
            app.ShowOnlyExchangeReactionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowOnlyExchangeReactionsCheckBoxValueChanged, true);
            app.ShowOnlyExchangeReactionsCheckBox.Text = 'Show Only Exchange Reactions';
            app.ObjectivetoAttackButtonGroup = uibuttongroup(app.DTItoolbox);
            app.ObjectivetoAttackButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ObjectivetoAttackButtonGroupSelectionChanged, true);
            app.ObjectivetoAttackButtonGroup.Title = 'Objective to Attack:';
            app.ProliferationBiomassProductionButton = uiradiobutton(app.ObjectivetoAttackButtonGroup);
            app.ProliferationBiomassProductionButton.Text = 'Proliferation (Biomass Production)';
            app.ProliferationBiomassProductionButton.Value = true;
            app.VirulenceFactorProductionButton = uiradiobutton(app.ObjectivetoAttackButtonGroup);
            app.VirulenceFactorProductionButton.Text = 'Virulence Factor Production';
            app.ReportBasedonButtonGroup = uibuttongroup(app.DTItoolbox);
            app.ReportBasedonButtonGroup.Title = 'Report Based on:';
            app.ReactionSetsButton = uiradiobutton(app.ReportBasedonButtonGroup);
            app.ReactionSetsButton.Text = 'Reaction Sets';
            app.ReactionSetsButton.Value = true;
            app.GeneSetsButton = uiradiobutton(app.ReportBasedonButtonGroup);
            app.GeneSetsButton.Text = 'Gene Sets';
            app.UITable2 = uitable(app.DTItoolbox);
            app.UITable2.ColumnName = {'Metabolite'};
            app.UITable2.RowName = {''};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.MetaboliteCentricApproachCheckBox = uicheckbox(app.DTItoolbox);
            app.MetaboliteCentricApproachCheckBox.ValueChangedFcn = createCallbackFcn(app, @MetaboliteCentricApproachCheckBoxValueChanged, true);
            app.MetaboliteCentricApproachCheckBox.Text = 'Metabolite-Centric Approach';
            app.StartAnalysisButton = uibutton(app.DTItoolbox, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.AddtoTargetsButton = uibutton(app.DTItoolbox, 'push');
            app.AddtoTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTargetsButtonPushed, true);
            app.AddtoTargetsButton.Text = 'Add to Targets';
            app.MaximumCardinalitySpinnerLabel = uilabel(app.DTItoolbox);
            app.MaximumCardinalitySpinnerLabel.Text = 'Maximum Cardinality:';
            app.MaximumCardinalitySpinner = uispinner(app.DTItoolbox);
            app.MaximumCardinalitySpinner.Limits = [1 Inf];
            app.MaximumCardinalitySpinner.Value = 1;
            app.CutoffRatioSpinnerLabel = uilabel(app.DTItoolbox);
            app.CutoffRatioSpinnerLabel.Text = 'Cutoff Ratio:';
            app.CutoffRatioSpinner = uispinner(app.DTItoolbox);
            app.CutoffRatioSpinner.Limits = [0 1];
            app.CutoffRatioSpinner.Value = 0.01;
            app.ViewListofTargetsButton = uibutton(app.DTItoolbox, 'push');
            app.ViewListofTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewListofTargetsButtonPushed, true);
            app.ViewListofTargetsButton.Text = 'View List of Targets';
            app.RemoveAddedTargetsButton = uibutton(app.DTItoolbox, 'push');
            app.RemoveAddedTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAddedTargetsButtonPushed, true);
            app.RemoveAddedTargetsButton.Text = 'Remove Added Targets';
            app.NumberofWorkersLabel = uilabel(app.DTItoolbox);
            app.NumberofWorkersLabel.Text = 'Number of Workers:';
            app.NumberofWorkersSpinner = uispinner(app.DTItoolbox);
            app.NumberofWorkersSpinner.Limits = [2 Inf];
            app.NumberofWorkersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofWorkersSpinnerValueChanged, true);
            app.NumberofWorkersSpinner.Value = 4;
            app.ResetNoofWorkersButton = uibutton(app.DTItoolbox, 'push');
            app.ResetNoofWorkersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetNoofWorkersButtonPushed, true);
            app.ResetNoofWorkersButton.Text = 'Reset No. of Workers';
            app.SearchEditFieldLabel = uilabel(app.DTItoolbox);
            app.SearchEditFieldLabel.Text = 'Search:';
            app.SearchEditField = uieditfield(app.DTItoolbox, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.DTItoolbox.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTItool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DTItoolbox)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DTItoolbox)
        end
    end
end